classdef Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SupermarketSimulatorLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        BasicconfigurationTab           matlab.ui.container.Tab
        ConfigureyourownsupermarketLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ClusterheadsLabel               matlab.ui.control.Label
        RangesLabel                     matlab.ui.control.Label
        ElectronicShelfLabelsSliderLabel  matlab.ui.control.Label
        ElectronicShelfLabelsSlider     matlab.ui.control.Slider
        TotalnumberEditFieldLabel       matlab.ui.control.Label
        TotalnumberEditField            matlab.ui.control.NumericEditField
        ClustersperrowEditFieldLabel    matlab.ui.control.Label
        ClustersperrowEditField         matlab.ui.control.NumericEditField
        SeparationEditFieldLabel        matlab.ui.control.Label
        SeparationEditField             matlab.ui.control.NumericEditField
        TagsrangeEditFieldLabel         matlab.ui.control.Label
        TagsrangeEditField              matlab.ui.control.EditField
        ClusterheadsrangeEditFieldLabel  matlab.ui.control.Label
        ClusterheadsrangeEditField      matlab.ui.control.EditField
        ServerrangeEditFieldLabel       matlab.ui.control.Label
        ServerrangeEditField            matlab.ui.control.EditField
        ChanceoffailureduetointerferenceSliderLabel  matlab.ui.control.Label
        ChanceoffailureduetointerferenceSlider  matlab.ui.control.Slider
        UpdateallnodesTab               matlab.ui.container.Tab
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        GOButton                        matlab.ui.control.Button
        ThelastESLwasupdatedafterEditFieldLabel  matlab.ui.control.Label
        ThelastESLwasupdatedafterEditField  matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        msLabel_5                       matlab.ui.control.Label
        ofthetagswereupdatedinEditFieldLabel  matlab.ui.control.Label
        ofthetagswereupdatedinEditField  matlab.ui.control.NumericEditField
        WARNINGTheexecutioncanbeveryslowconsiderusing1000tagsLabel  matlab.ui.control.Label
        Update1nodeTab                  matlab.ui.container.Tab
        DescriptionTextArea_2Label      matlab.ui.control.Label
        DescriptionTextArea_2           matlab.ui.control.TextArea
        GOButton_2                      matlab.ui.control.Button
        msLabel_2                       matlab.ui.control.Label
        TheESLwasupdatedafterEditFieldLabel  matlab.ui.control.Label
        TheESLwasupdatedafterEditField  matlab.ui.control.NumericEditField
        CustomersshoppingTab            matlab.ui.container.Tab
        GOButton_3                      matlab.ui.control.Button
        msLabel_3                       matlab.ui.control.Label
        DescriptionTextArea_3Label      matlab.ui.control.Label
        DescriptionTextArea_3           matlab.ui.control.TextArea
        AveragelatencyEditFieldLabel    matlab.ui.control.Label
        AveragelatencyEditField         matlab.ui.control.NumericEditField
        SimulationlengthmsEditFieldLabel  matlab.ui.control.Label
        SimulationlengthmsEditField     matlab.ui.control.NumericEditField
        ClientsEditFieldLabel           matlab.ui.control.Label
        ClientsEditField                matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        WorstlatencyEditFieldLabel      matlab.ui.control.Label
        WorstlatencyEditField           matlab.ui.control.NumericEditField
        msLabel_4                       matlab.ui.control.Label
        CommunicationscompletedEditFieldLabel  matlab.ui.control.Label
        CommunicationscompletedEditField  matlab.ui.control.NumericEditField
        CommunicationspendingEditFieldLabel  matlab.ui.control.Label
        CommunicationspendingEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        nc=80; % Number of clusters
        nt=5000; % Number of tags
        gridx=10;
        dis=10;
        commFailProb=0.3;
        range_of_nodes=15;
        range_of_clusterheads=25;
        range_of_server=25;
        clients=200;
        sim_time=1e5;
        worst_latency;
        comm_list;
        pending;
    end

    methods (Access = private)
    
        function [C, nc] = clusters(app, nc, gridx, dis)
            %Coordinators generation
            gridy=floor(nc/gridx);
            C(nc,2)=0; %nc number of coordinators, with 3 dimensional coordinates each
            x=dis/2;
            y=dis/2;
            for i=1:gridx
                for j=1:gridy
                    C((i-1)*gridy+j,:)=[x,y];
                    y=y+dis;
                end
                y=dis/2;
                x=x+dis;
            end
            nc=gridx*gridy;
        end
        
        function []= displayMode3Results(app,comm,pend)
            latencies(size(comm,2))=0;
            for i=1:size(comm,2)
                latencies(i)=comm(i).latency;
            end
            histogram(app.UIAxes2,latencies);
            app.AveragelatencyEditField.Value=mean(latencies);
            app.WorstlatencyEditField.Value=max(latencies);
            app.CommunicationscompletedEditField.Value=size(comm,2);
            app.CommunicationspendingEditField.Value=pend;
        end
        
        function [] = displayMode1Results(app,comm)
            latencies(size(comm,2))=0;
            for i=1:size(comm,2)
                latencies(i)=comm(i).latency;
            end
            histogram(app.UIAxes3,latencies);
            app.ThelastESLwasupdatedafterEditField.Value=max(latencies);
            app.ofthetagswereupdatedinEditField.Value=prctile(latencies,90);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nc = app.TotalnumberEditField.Value;
            app.gridx = app.ClustersperrowEditField.Value;
            app.dis = app.SeparationEditField.Value;
            [C, app.nc] = clusters(app, app.nc, app.gridx, app.dis);
            scatter(app.UIAxes,C(:,1),C(:,2),30);
            app.UIAxes.YLim=[0 (app.nc/app.gridx)*app.dis];
            app.UIAxes.XLim=[0 app.gridx*app.dis];
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
            
        end

        % Value changed function: ElectronicShelfLabelsSlider
        function ElectronicShelfLabelsSliderValueChanged(app, event)
            app.nt = floor(app.ElectronicShelfLabelsSlider.Value);
        end

        % Value changing function: ElectronicShelfLabelsSlider
        function ElectronicShelfLabelsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text=num2str(floor(changingValue));
        end

        % Value changed function: TotalnumberEditField
        function TotalnumberEditFieldValueChanged(app, event)
            app.nc = app.TotalnumberEditField.Value;
            app.gridx = app.ClustersperrowEditField.Value;
            app.dis = app.SeparationEditField.Value;
            [C, app.nc] = clusters(app, app.nc, app.gridx, app.dis);
            scatter(app.UIAxes,C(:,1),C(:,2),30);
            app.UIAxes.YLim=[0 (app.nc/app.gridx)*app.dis];
            app.UIAxes.XLim=[0 app.gridx*app.dis];
            app.TotalnumberEditField.Value=app.nc;
        end

        % Value changed function: ClustersperrowEditField
        function ClustersperrowEditFieldValueChanged(app, event)
            app.nc = app.TotalnumberEditField.Value;
            app.gridx = app.ClustersperrowEditField.Value;
            app.dis = app.SeparationEditField.Value;
            [C, app.nc] = clusters(app, app.nc, app.gridx, app.dis);
            scatter(app.UIAxes,C(:,1),C(:,2),30);
            app.UIAxes.YLim=[0 (app.nc/app.gridx)*app.dis];
            app.UIAxes.XLim=[0 app.gridx*app.dis];
            app.TotalnumberEditField.Value=app.nc;
        end

        % Value changed function: SeparationEditField
        function SeparationEditFieldValueChanged(app, event)
            app.nc = app.TotalnumberEditField.Value;
            app.gridx = app.ClustersperrowEditField.Value;
            app.dis = app.SeparationEditField.Value;
            [C, app.nc] = clusters(app, app.nc, app.gridx, app.dis);
            app.UIAxes.YLim=[0 (app.nc/app.gridx)*app.dis];
            app.UIAxes.XLim=[0 app.gridx*app.dis];
            scatter(app.UIAxes,C(:,1),C(:,2),30);
        end

        % Value changed function: TagsrangeEditField
        function TagsrangeEditFieldValueChanged(app, event)
            app.range_of_nodes = app.TagsrangeEditField.Value;

        end

        % Value changed function: ClusterheadsrangeEditField
        function ClusterheadsrangeEditFieldValueChanged(app, event)
            app.range_of_clusterheads = app.ClusterheadsrangeEditField.Value;

        end

        % Value changed function: ServerrangeEditField
        function ServerrangeEditFieldValueChanged(app, event)
            app.range_of_server = app.ServerrangeEditField.Value;
            
        end

        % Value changed function: 
        % ChanceoffailureduetointerferenceSlider
        function ChanceoffailureduetointerferenceSliderValueChanged(app, event)
            app.commFailProb = app.ChanceoffailureduetointerferenceSlider.Value / 100;
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            assignin('base','nc',app.nc);
            assignin('base','nt',app.nt);
            assignin('base','gridx',app.gridx);
            assignin('base','dis',app.dis);
            assignin('base','commFailProb',app.commFailProb);
            assignin('base','range_of_nodes',app.range_of_nodes);
            assignin('base','range_of_clusterheads',app.range_of_clusterheads);
            assignin('base','range_of_server',app.range_of_server);
            Mode1
            comm=app.comm_list;
            displayMode1Results(app,comm);
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            assignin('base','nc',app.nc);
            assignin('base','nt',app.nt);
            assignin('base','gridx',app.gridx);
            assignin('base','dis',app.dis);
            assignin('base','commFailProb',app.commFailProb);
            assignin('base','range_of_nodes',app.range_of_nodes);
            assignin('base','range_of_clusterheads',app.range_of_clusterheads);
            assignin('base','range_of_server',app.range_of_server);
            Mode2
            app.TheESLwasupdatedafterEditField.Value=app.worst_latency;
        end

        % Value changed function: SimulationlengthmsEditField
        function SimulationlengthmsEditFieldValueChanged(app, event)
            app.sim_time = app.SimulationlengthmsEditField.Value;
            
        end

        % Value changed function: ClientsEditField
        function ClientsEditFieldValueChanged(app, event)
            app.clients = app.ClientsEditField.Value;
            
        end

        % Button pushed function: GOButton_3
        function GOButton_3Pushed(app, event)
            assignin('base','nc',app.nc);
            assignin('base','nt',app.nt);
            assignin('base','gridx',app.gridx);
            assignin('base','dis',app.dis);
            assignin('base','commFailProb',app.commFailProb);
            assignin('base','range_of_nodes',app.range_of_nodes);
            assignin('base','range_of_clusterheads',app.range_of_clusterheads);
            assignin('base','range_of_server',app.range_of_server);
            assignin('base','clients',app.clients);
            assignin('base','sim_time',app.sim_time);
            Mode3
            comm=app.comm_list;
            pend=app.pending;
            if (size(comm,2)~=0)
                displayMode3Results(app,comm,pend);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SupermarketSimulatorLabel = uilabel(app.UIFigure);
            app.SupermarketSimulatorLabel.Text = 'Supermarket Simulator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicconfigurationTab = uitab(app.TabGroup);
            app.BasicconfigurationTab.Title = 'Basic configuration';
            app.ConfigureyourownsupermarketLabel = uilabel(app.BasicconfigurationTab);
            app.ConfigureyourownsupermarketLabel.Text = 'Configure your own supermarket';
            app.Label = uilabel(app.BasicconfigurationTab);
            app.Label.Text = '5000';
            app.UIAxes = uiaxes(app.BasicconfigurationTab);
            app.ClusterheadsLabel = uilabel(app.BasicconfigurationTab);
            app.ClusterheadsLabel.Text = 'Clusterheads';
            app.RangesLabel = uilabel(app.BasicconfigurationTab);
            app.RangesLabel.Text = 'Ranges';
            app.ElectronicShelfLabelsSliderLabel = uilabel(app.BasicconfigurationTab);
            app.ElectronicShelfLabelsSliderLabel.Text = 'Electronic Shelf Labels';
            app.ElectronicShelfLabelsSlider = uislider(app.BasicconfigurationTab);
            app.ElectronicShelfLabelsSlider.Limits = [1 10000];
            app.ElectronicShelfLabelsSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '', '', '1e+04'};
            app.ElectronicShelfLabelsSlider.ValueChangedFcn = createCallbackFcn(app, @ElectronicShelfLabelsSliderValueChanged, true);
            app.ElectronicShelfLabelsSlider.ValueChangingFcn = createCallbackFcn(app, @ElectronicShelfLabelsSliderValueChanging, true);
            app.ElectronicShelfLabelsSlider.Value = 5000;
            app.TotalnumberEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.TotalnumberEditFieldLabel.Text = 'Total number ';
            app.TotalnumberEditField = uieditfield(app.BasicconfigurationTab, 'numeric');
            app.TotalnumberEditField.ValueChangedFcn = createCallbackFcn(app, @TotalnumberEditFieldValueChanged, true);
            app.TotalnumberEditField.Value = 80;
            app.ClustersperrowEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.ClustersperrowEditFieldLabel.Text = 'Clusters per row';
            app.ClustersperrowEditField = uieditfield(app.BasicconfigurationTab, 'numeric');
            app.ClustersperrowEditField.ValueChangedFcn = createCallbackFcn(app, @ClustersperrowEditFieldValueChanged, true);
            app.ClustersperrowEditField.Value = 10;
            app.SeparationEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.SeparationEditFieldLabel.Text = 'Separation';
            app.SeparationEditField = uieditfield(app.BasicconfigurationTab, 'numeric');
            app.SeparationEditField.ValueChangedFcn = createCallbackFcn(app, @SeparationEditFieldValueChanged, true);
            app.SeparationEditField.Value = 10;
            app.TagsrangeEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.TagsrangeEditFieldLabel.Text = 'Tags range';
            app.TagsrangeEditField = uieditfield(app.BasicconfigurationTab, 'text');
            app.TagsrangeEditField.ValueChangedFcn = createCallbackFcn(app, @TagsrangeEditFieldValueChanged, true);
            app.TagsrangeEditField.Value = '10';
            app.ClusterheadsrangeEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.ClusterheadsrangeEditFieldLabel.Text = 'Clusterheads range';
            app.ClusterheadsrangeEditField = uieditfield(app.BasicconfigurationTab, 'text');
            app.ClusterheadsrangeEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterheadsrangeEditFieldValueChanged, true);
            app.ClusterheadsrangeEditField.Value = '25';
            app.ServerrangeEditFieldLabel = uilabel(app.BasicconfigurationTab);
            app.ServerrangeEditFieldLabel.Text = 'Server range';
            app.ServerrangeEditField = uieditfield(app.BasicconfigurationTab, 'text');
            app.ServerrangeEditField.ValueChangedFcn = createCallbackFcn(app, @ServerrangeEditFieldValueChanged, true);
            app.ServerrangeEditField.Value = '25';
            app.ChanceoffailureduetointerferenceSliderLabel = uilabel(app.BasicconfigurationTab);
            app.ChanceoffailureduetointerferenceSliderLabel.Text = 'Chance of failure due to interference (%)';
            app.ChanceoffailureduetointerferenceSlider = uislider(app.BasicconfigurationTab);
            app.ChanceoffailureduetointerferenceSlider.ValueChangedFcn = createCallbackFcn(app, @ChanceoffailureduetointerferenceSliderValueChanged, true);
            app.ChanceoffailureduetointerferenceSlider.Value = 30;
            app.UpdateallnodesTab = uitab(app.TabGroup);
            app.UpdateallnodesTab.Title = 'Update all nodes';
            app.DescriptionTextAreaLabel = uilabel(app.UpdateallnodesTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.UpdateallnodesTab);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Value = {'In this mode, the server will update the information in all the Electronic Shelf Labels (ESL) in the shop.'};
            app.GOButton = uibutton(app.UpdateallnodesTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.ThelastESLwasupdatedafterEditFieldLabel = uilabel(app.UpdateallnodesTab);
            app.ThelastESLwasupdatedafterEditFieldLabel.Text = 'The last ESL was updated after';
            app.ThelastESLwasupdatedafterEditField = uieditfield(app.UpdateallnodesTab, 'numeric');
            app.ThelastESLwasupdatedafterEditField.Editable = 'off';
            app.msLabel = uilabel(app.UpdateallnodesTab);
            app.msLabel.Text = 'ms';
            app.UIAxes3 = uiaxes(app.UpdateallnodesTab);
            title(app.UIAxes3, 'Latency distribution')
            xlabel(app.UIAxes3, 'Delay')
            ylabel(app.UIAxes3, 'Tags')
            app.msLabel_5 = uilabel(app.UpdateallnodesTab);
            app.msLabel_5.Text = 'ms';
            app.ofthetagswereupdatedinEditFieldLabel = uilabel(app.UpdateallnodesTab);
            app.ofthetagswereupdatedinEditFieldLabel.Text = '90% of the tags were updated in';
            app.ofthetagswereupdatedinEditField = uieditfield(app.UpdateallnodesTab, 'numeric');
            app.ofthetagswereupdatedinEditField.Editable = 'off';
            app.WARNINGTheexecutioncanbeveryslowconsiderusing1000tagsLabel = uilabel(app.UpdateallnodesTab);
            app.WARNINGTheexecutioncanbeveryslowconsiderusing1000tagsLabel.Text = 'WARNING: The execution can be very slow, consider using <1000 tags.';
            app.Update1nodeTab = uitab(app.TabGroup);
            app.Update1nodeTab.Title = 'Update 1 node';
            app.DescriptionTextArea_2Label = uilabel(app.Update1nodeTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_2 = uitextarea(app.Update1nodeTab);
            app.DescriptionTextArea_2.Editable = 'off';
            app.DescriptionTextArea_2.Value = {'In this mode, the server will update the information of ONE Electronic Shelf Label (ESL) in the shop chosen randomly.'; ''};
            app.GOButton_2 = uibutton(app.Update1nodeTab, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Text = 'GO!';
            app.msLabel_2 = uilabel(app.Update1nodeTab);
            app.msLabel_2.Text = 'ms';
            app.TheESLwasupdatedafterEditFieldLabel = uilabel(app.Update1nodeTab);
            app.TheESLwasupdatedafterEditFieldLabel.Text = 'The ESL was updated after';
            app.TheESLwasupdatedafterEditField = uieditfield(app.Update1nodeTab, 'numeric');
            app.TheESLwasupdatedafterEditField.Editable = 'off';
            app.CustomersshoppingTab = uitab(app.TabGroup);
            app.CustomersshoppingTab.Title = 'Customers shopping';
            app.GOButton_3 = uibutton(app.CustomersshoppingTab, 'push');
            app.GOButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOButton_3Pushed, true);
            app.GOButton_3.Text = 'GO!';
            app.msLabel_3 = uilabel(app.CustomersshoppingTab);
            app.msLabel_3.Text = 'ms';
            app.DescriptionTextArea_3Label = uilabel(app.CustomersshoppingTab);
            app.DescriptionTextArea_3Label.Text = 'Description';
            app.DescriptionTextArea_3 = uitextarea(app.CustomersshoppingTab);
            app.DescriptionTextArea_3.Editable = 'off';
            app.DescriptionTextArea_3.Value = {'In this mode, we will simulate customers picking up objects randomly around the shop. After a product is picked up, the Electronic Shelf Label (ESL) must update the shopping list on the server.'; 'The simulation will run for a time given by the user (simulation time), this is the time in the simulation, not the real time. We will assume that a client picks up 1 object approximately every 30 s.'; ''};
            app.AveragelatencyEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.AveragelatencyEditFieldLabel.Text = 'Average latency';
            app.AveragelatencyEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.AveragelatencyEditField.Editable = 'off';
            app.SimulationlengthmsEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.SimulationlengthmsEditFieldLabel.Text = 'Simulation length (ms)';
            app.SimulationlengthmsEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.SimulationlengthmsEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationlengthmsEditFieldValueChanged, true);
            app.SimulationlengthmsEditField.Value = 100000;
            app.ClientsEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.ClientsEditFieldLabel.Text = 'Clients';
            app.ClientsEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.ClientsEditField.ValueChangedFcn = createCallbackFcn(app, @ClientsEditFieldValueChanged, true);
            app.ClientsEditField.Value = 200;
            app.UIAxes2 = uiaxes(app.CustomersshoppingTab);
            title(app.UIAxes2, 'Latency distribution')
            xlabel(app.UIAxes2, 'Latency')
            ylabel(app.UIAxes2, 'Communications')
            app.WorstlatencyEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.WorstlatencyEditFieldLabel.Text = 'Worst latency';
            app.WorstlatencyEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.WorstlatencyEditField.Editable = 'off';
            app.msLabel_4 = uilabel(app.CustomersshoppingTab);
            app.msLabel_4.Text = 'ms';
            app.CommunicationscompletedEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.CommunicationscompletedEditFieldLabel.Text = 'Communications completed';
            app.CommunicationscompletedEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.CommunicationscompletedEditField.Editable = 'off';
            app.CommunicationspendingEditFieldLabel = uilabel(app.CustomersshoppingTab);
            app.CommunicationspendingEditFieldLabel.Text = 'Communications pending';
            app.CommunicationspendingEditField = uieditfield(app.CustomersshoppingTab, 'numeric');
            app.CommunicationspendingEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end